classdef computer_vision < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CompositeImageCreatorUIFigure  matlab.ui.Figure
        btnTarget                      matlab.ui.control.Button
        btnBrowseLabel                 matlab.ui.control.Label
        XLabel                         matlab.ui.control.Label
        numPixelsX                     matlab.ui.control.NumericEditField
        btnSubmit                      matlab.ui.control.Button
        PleaseselectthetypeofcompositionbuilderDropDownLabel  matlab.ui.control.Label
        compositionBuilder             matlab.ui.control.DropDown
        YLabel                         matlab.ui.control.Label
        numPixelsY                     matlab.ui.control.NumericEditField
        NumberoftilesDropDownLabel     matlab.ui.control.Label
        numTiles                       matlab.ui.control.DropDown
        btnSource                      matlab.ui.control.Button
        PleaseselectyoursourceimagedirectoryLabel  matlab.ui.control.Label
    end

    properties (Access = private)
        
        % Target Image Properties
        targetImage
        targetImageHeight
        targetImageWidth
        
        % Source Image Properties
        srcImageDirectory
        srcImageSet
        
        % Tiling Properties
        tileWidth
        tileHeight
        
        % AVG Grayscale Composition Properties
        srcImageGrayMean
        
        % AVG RGB Composition Properties
        srcImageRGBMean
        
        % CCV Composition Properties
        srcCCVValues
        
        % Final Image Properties
        compositeImage
        compositeImageFun
        
    end

    methods (Access = private)
        
        % Resize the target image to user defined scale
        function resizeTargetImage(app)
            app.targetImage = imresize(app.targetImage, [app.numPixelsY.Value, app.numPixelsX.Value]);
        end
        
        % Segment and create composite image 
        function createCompositeImage(app)
            
            app.compositeImage = blockproc(app.targetImage, [app.tileWidth, app.tileHeight], app.compositeImageFun, 'UseParallel', true);
            
        end
        
        %%%%
        %%%% Building blocks for RGB Analysis
        %%%%
        function buildCompositeLibraryRGBValues(app)
        
            % https://www.mathworks.com/matlabcentral/answers/160076-how-to-read-multiple-images-and-calculate-mean
            meanEachImage = arrayfun(@(x) mean(reshape(imresize(app.srcImageSet.read(x),[app.tileWidth, app.tileHeight]),[],3)), (1:app.srcImageSet.Count)','UniformOutput',false);
            
            meanImages = [];
            
            for i = 1 : size(meanEachImage)
                meanImages = [meanImages;mean(cell2mat(meanEachImage(i)))];
            end
            
            app.srcImageRGBMean = meanImages;
        
        end
        
        function block = buildRGBComposite(app, imblock)
            
            blockValue = mean(imblock);
            
            blockValue = blockValue(:,:,1);
            
            blockValue = blockValue(1);
            
            block = findRGBMatch(app, blockValue);
        
        end
        
        function match = findRGBMatch(app, blockValue)
            
            % Find srcImageDirectory index of the best matching rgb value
            [c index] = min(abs(app.srcImageRGBMean-blockValue));
            
            val = app.srcImageSet.read(index);
         
            match = imresize(val, [app.tileWidth, app.tileHeight]);
            
        end
        
        %%%%
        %%%% Building blocks for GRAY Analysis
        %%%%
        
        %%%%
        %%%% Building blocks for CCV Analysis
        %%%%
        
        function buildCompositeLibraryCCVValues(app)
                    
            ICCV = arrayfun(@(x) reshape(getICCV(imresize(app.srcImageSet.read(x), [app.tileWidth, app.tileHeight])), [], 4), (1:app.srcImageSet.Count)','UniformOutput',false);
            
            app.srcCCVValues = ICCV;
        
        end
        
        function block = buildCCVComposite(app, imblock)
                        
            blockValue = reshape(getICCV(imblock), [], 4);
            
            block = findCCVMatch(app, blockValue);
        
        end
        
        function match = findCCVMatch(app, blockValue)
            
            bestMatch = 10000;
            bestMatchIndex = 1;
            
            for i = 1 : length(app.srcCCVValues)
                [c index] = min(abs(app.srcCCVValues{i})-blockValue);
                
                if c <= bestMatch
                    bestMatch = c;
                    bestMatchIndex = i;
                end
            end
            
            val = app.srcImageSet.read(bestMatchIndex);
         
            match = imresize(val, [app.tileWidth, app.tileHeight]);
            
        end
    end


    methods (Access = private)

        % Button pushed function: btnTarget
        function btnTargetPushed(app, event)
            % Find our target image and assign it to the global value app.targetImage
            
            [filename, pathname] = uigetfile( ...
                {'*.jpg';'*.png';'*.bmp';'*.*'}, ...
                'Pick an file');
            
            app.targetImage = imread(strcat(pathname, filename));
        end

        % Button pushed function: btnSubmit
        function btnSubmitPushed(app, event)
            resizeTargetImage(app);
            
            dimension = sqrt(str2num(app.numTiles.Value));
            
            app.tileWidth = app.numPixelsY.Value/dimension;
            app.tileHeight = app.numPixelsX.Value/dimension;
            
            % Determines how the composite image shall be created
            switch(app.compositionBuilder.Value)
                case 'Avg. RGB Values'
                    app.compositeImageFun = @(block_struct) buildRGBComposite(app,block_struct.data);
                    buildCompositeLibraryRGBValues(app);
                    
                case 'Avg. Grayscale Values'
                    
                case 'Colour Coherence Vector'
                    app.compositeImageFun = @(block_struct) buildCCVComposite(app,block_struct.data);
                    buildCompositeLibraryCCVValues(app);
                    
                otherwise
                    fprintf('Error');
            end
            
            createCompositeImage(app);
            
            imshow(app.compositeImage);
        end

        % Button pushed function: btnSource
        function btnSourcePushed(app, event)
            app.srcImageDirectory = uigetdir;
            
            app.srcImageSet = imageSet(fullfile(app.srcImageDirectory));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CompositeImageCreatorUIFigure = uifigure;
            app.CompositeImageCreatorUIFigure.Name = 'Composite Image Creator';
            app.btnTarget = uibutton(app.CompositeImageCreatorUIFigure, 'push');
            app.btnTarget.ButtonPushedFcn = createCallbackFcn(app, @btnTargetPushed, true);
            app.btnTarget.Text = 'Browse...';
            app.btnBrowseLabel = uilabel(app.CompositeImageCreatorUIFigure);
            app.btnBrowseLabel.Text = 'Please select your target image:';
            app.XLabel = uilabel(app.CompositeImageCreatorUIFigure);
            app.XLabel.Text = 'X';
            app.numPixelsX = uieditfield(app.CompositeImageCreatorUIFigure, 'numeric');
            app.numPixelsX.Limits = [1 Inf];
            app.numPixelsX.ValueDisplayFormat = '%.0f';
            app.numPixelsX.Value = 1;
            app.btnSubmit = uibutton(app.CompositeImageCreatorUIFigure, 'push');
            app.btnSubmit.ButtonPushedFcn = createCallbackFcn(app, @btnSubmitPushed, true);
            app.btnSubmit.Text = 'Submit';
            app.PleaseselectthetypeofcompositionbuilderDropDownLabel = uilabel(app.CompositeImageCreatorUIFigure);
            app.PleaseselectthetypeofcompositionbuilderDropDownLabel.Text = 'Please select the type of composition builder';
            app.compositionBuilder = uidropdown(app.CompositeImageCreatorUIFigure);
            app.compositionBuilder.Items = {'Avg. RGB Values', 'Avg. Grayscale Values', 'Colour Coherence Vector'};
            app.compositionBuilder.Value = 'Avg. RGB Values';
            app.YLabel = uilabel(app.CompositeImageCreatorUIFigure);
            app.YLabel.Text = 'Y';
            app.numPixelsY = uieditfield(app.CompositeImageCreatorUIFigure, 'numeric');
            app.numPixelsY.Limits = [1 Inf];
            app.numPixelsY.ValueDisplayFormat = '%.0f';
            app.numPixelsY.Value = 1;
            app.NumberoftilesDropDownLabel = uilabel(app.CompositeImageCreatorUIFigure);
            app.NumberoftilesDropDownLabel.Text = 'Number of tiles';
            app.numTiles = uidropdown(app.CompositeImageCreatorUIFigure);
            app.numTiles.Items = {'49', '121', '225', '361', '484', '576', '841', '961', '1156', '1369', '1600', '2025', '2304', '2500', '2809', '3364', '3724', '4489', '4900', '5776', '7225', '10000'};
            app.numTiles.Value = '49';
            app.btnSource = uibutton(app.CompositeImageCreatorUIFigure, 'push');
            app.btnSource.ButtonPushedFcn = createCallbackFcn(app, @btnSourcePushed, true);
            app.btnSource.Text = 'Browse...';
            app.PleaseselectyoursourceimagedirectoryLabel = uilabel(app.CompositeImageCreatorUIFigure);
            app.PleaseselectyoursourceimagedirectoryLabel.Text = 'Please select your source image directory';
        end
    end

    methods (Access = public)

        % Construct app
        function app = computer_vision

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CompositeImageCreatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CompositeImageCreatorUIFigure)
        end
    end
end